classdef GINSPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Toolbar                matlab.ui.container.Toolbar
        PushTool               matlab.ui.container.toolbar.PushTool
        PushTool2              matlab.ui.container.toolbar.PushTool
        PushTool3              matlab.ui.container.toolbar.PushTool
        PushTool4              matlab.ui.container.toolbar.PushTool
        GridLayout             matlab.ui.container.GridLayout
        Panel_4                matlab.ui.container.Panel
        Label_Info             matlab.ui.control.Label
        Panel_3                matlab.ui.container.Panel
        GridLayout2_2          matlab.ui.container.GridLayout
        GNSSCheckBox           matlab.ui.control.CheckBox
        Button_18              matlab.ui.control.Button
        GridLayout5            matlab.ui.container.GridLayout
        CheckBox_3             matlab.ui.control.CheckBox
        CheckBox_2             matlab.ui.control.CheckBox
        CheckBox               matlab.ui.control.CheckBox
        Button_17              matlab.ui.control.Button
        Button_16              matlab.ui.control.Button
        EditField_gins_path    matlab.ui.control.EditField
        Label_7                matlab.ui.control.Label
        EditField_laser_path   matlab.ui.control.EditField
        Label_6                matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        EditField_gnss_path    matlab.ui.control.EditField
        Label_8                matlab.ui.control.Label
        Button_19              matlab.ui.control.Button
        Button_15              matlab.ui.control.Button
        Button_14              matlab.ui.control.Button
        EditField_yaml_path    matlab.ui.control.EditField
        Label_5                matlab.ui.control.Label
        EditField_result_path  matlab.ui.control.EditField
        Label_4                matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        GridLayout6            matlab.ui.container.GridLayout
        Button_20              matlab.ui.control.Button
        FIlter                 matlab.ui.control.CheckBox
        Fixed_lagCheckBox      matlab.ui.control.CheckBox
        RTSCheckBox            matlab.ui.control.CheckBox
        GridLayout4_3          matlab.ui.container.GridLayout
        Button_13              matlab.ui.control.Button
        Button_12              matlab.ui.control.Button
        Button_11              matlab.ui.control.Button
        Button_10              matlab.ui.control.Button
        Button_9               matlab.ui.control.Button
        NEDButton_2            matlab.ui.control.Button
        NEDButton              matlab.ui.control.Button
        Label_3                matlab.ui.control.Label
        GridLayout4_2          matlab.ui.container.GridLayout
        Button_8               matlab.ui.control.Button
        Button_7               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Label_2                matlab.ui.control.Label
        GridLayout4            matlab.ui.container.GridLayout
        Button                 matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Label                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        D2R double = 0.017453292519943;
        gins_data double
        imu_err double
        imu_err_rts double
        laser_data double
        load_result logical = false;
        load_yaml logical = false;
        navdata double
        navdata_rts
        options % 配置文件
        refpos double
        run_time double
        run_time_rts double
        std_filter double
        std_rts double
        fixedlag_data double
        fixedlag_imu_err double
        fixedlag_std double
        % Description
        fixedlag_time % Description
        SelectedCompareItems % Description
        gnss_data % Description
        load_gnss logical
        imu % Description
        rtsimu
        flimu
    end
    
methods (Access = private)
               
        function readGinsPav(app, fname)
            fid2 = fopen(fname, 'r');
            [data, N]  = fread(fid2, 'double');
            data = reshape(data, 44, N/44)';
            fclose(fid2);
            app.gins_data = data;
        end

        function load_results(app)
            path = app.EditField_result_path.Value;
            starttime = app.options.starttime;
            fid = fopen(path + "/KF_GINS_Navresult.nav");
            [data, N] = fread(fid, 'double');
            fclose(fid);
            app.navdata = reshape(data, 11, N/11)';
            endtime = app.navdata(end,2);
            if app.options.isoutimuerr
                fid = fopen(path + "/KF_GINS_IMU_ERR.bin");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.imu_err = reshape(data, 14, N/14)';
                endtime = min([endtime, app.imu_err(end,2)]);
            end
            if app.options.isoutstd
                if app.options.filtermode ==1
                    w = 23;
                elseif app.options.filtermode ==2
                    w = 17;
                end
                fid = fopen(path + "/KF_GINS_STD.bin");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.std_filter = reshape(data, w, N/w)';
                endtime = min([endtime, app.std_filter(end,2)]);
            end
            if app.options.isoutimu
                fid = fopen(path + "/KF_GINS_IMU.bin");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.imu = reshape(data, 11, N/11)';
                endtime = min([endtime, app.imu(end,2)]);
            end
            
            app.navdata = app.navdata(app.navdata(:, 2)>=starttime & app.navdata(:, 2)<=endtime , :);
            if app.options.isoutimuerr 
                app.imu_err = app.imu_err(app.imu_err(:, 2)>=starttime & app.imu_err(:, 2)<=endtime , :); 
            end
            if app.options.isoutstd 
                app.std_filter = app.std_filter(app.std_filter(:, 2)>=starttime & app.std_filter(:, 2)<=endtime , :);
            end                
                if app.options.isoutimu 
                    app.imu = app.imu(app.imu(:, 2)>=starttime & app.imu(:, 2)<=endtime , :);
                end
            
            app.run_time = app.navdata(:, 2) - starttime;

            if app.options.userts
                fid = fopen(path + "/KF_GINS_SM_Navresult.nav");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.navdata_rts = reshape(data, 11, N/11)';
                endtime = app.navdata_rts(end,2);
                if app.options.isoutimuerr
                    fid = fopen(path + "/KF_GINS_SM_IMU_ERR.bin");
                    [data, N] = fread(fid, 'double');
                    fclose(fid);
                    app.imu_err_rts = reshape(data, 14, N/14)';
                    endtime = min([endtime, app.imu_err_rts(end,2)]);
                end
                if app.options.isoutstd
                    fid = fopen(path + "/KF_GINS_SM_STD.bin");
                    [data, N] = fread(fid, 'double');
                    fclose(fid);
                    app.std_rts = reshape(data, w, N/w)';
                    endtime = min([endtime, app.std_rts(end,2)]);
                end
                if app.options.isoutimu
                    fid = fopen(path + "/KF_GINS_SM_IMU.bin");
                    [data, N] = fread(fid, 'double');
                    fclose(fid);
                    app.rtsimu = reshape(data, 11, N/11)';
                    endtime = min([endtime, app.rtsimu(end,2)]);
                end
                
                app.navdata_rts = app.navdata_rts(app.navdata_rts(:, 2)>=starttime & app.navdata_rts(:, 2)<=endtime , :);
                if app.options.isoutimuerr
                    app.imu_err_rts = app.imu_err_rts(app.imu_err_rts(:, 2)>=starttime & app.imu_err_rts(:, 2)<=endtime , :);
                end
                if app.options.isoutstd
                    app.std_rts = app.std_rts(app.std_rts(:, 2)>=starttime & app.std_rts(:, 2)<=endtime , :);
                end
                if app.options.isoutimu 
                    app.rtsimu = app.rtsimu(app.rtsimu(:, 2)>=starttime & app.rtsimu(:, 2)<=endtime , :);
                end

                app.run_time_rts = app.navdata_rts(:, 2) - starttime;
            end

            if app.options.usefixedlag
                fid = fopen(path + "/KF_GINS_FL_Navresult.nav");
                [data, N] = fread(fid, 'double');
                fclose(fid);
                app.fixedlag_data = reshape(data, 11, N/11)';
                endtime = app.fixedlag_data(end,2);
                if app.options.isoutimuerr
                    fid = fopen(path + "/KF_GINS_FL_IMU_ERR.bin");
                    [data, N] = fread(fid, 'double');
                    fclose(fid);
                    app.fixedlag_imu_err = reshape(data, 14, N/14)';
                    endtime = min([endtime, app.fixedlag_imu_err(end,2)]);
                end
                if app.options.isoutstd
                    fid = fopen(path + "/KF_GINS_FL_STD.bin");
                    [data, N] = fread(fid, 'double');
                    fclose(fid);
                    app.fixedlag_std = reshape(data, w, N/w)';
                    endtime = min([endtime, app.fixedlag_std(end,2)]);
                end
                if app.options.isoutimu
                    fid = fopen(path + "/KF_GINS_FL_IMU.bin");
                    [data, N] = fread(fid, 'double');
                    fclose(fid);
                    app.flimu = reshape(data, 11, N/11)';
                    endtime = min([endtime, app.flimu(end,2)]);
                end
                app.fixedlag_data = app.fixedlag_data(app.fixedlag_data(:, 2)>=starttime & app.fixedlag_data(:, 2)<=endtime , :);
                if app.options.isoutimuerr
                    app.fixedlag_imu_err = app.fixedlag_imu_err(app.fixedlag_imu_err(:, 2)>=starttime & app.fixedlag_imu_err(:, 2)<=endtime , :);
                end
                if app.options.isoutstd
                    app.fixedlag_std = app.fixedlag_std(app.fixedlag_std(:, 2)>=starttime & app.fixedlag_std(:, 2)<=endtime , :);
                end
                if app.options.isoutimu 
                    app.flimu = app.flimu(app.flimu(:, 2)>=starttime & app.flimu(:, 2)<=endtime , :);
                end

                app.fixedlag_time = app.fixedlag_data(:, 2) - starttime;
            end

            app.refpos = cell2mat(app.options.refpos);
            app.refpos(1:2) = deg2rad(app.refpos(1:2));
            lever = [0;0;0];
            if app.options.isbacklever
                 lever = DRi(app.refpos) * Euler2C(deg2rad(cell2mat(app.options.initatt))) * cell2mat(app.options.antlever);
            end
            app.refpos = app.refpos + lever;

            app.FIlter.Value = false;
            app.RTSCheckBox.Value = false;
            app.Fixed_lagCheckBox.Value = false;

            if ~app.options.userts
                app.RTSCheckBox.Value = false;
                app.RTSCheckBox.Enable = false;
            end

            if ~app.options.usefixedlag
                app.Fixed_lagCheckBox.Value = false;
                app.Fixed_lagCheckBox.Enable = false;
            end

            app.set_button_state(true);
            if app.options.isoutimu
                app.Button_20.Enable = true;
            else
                app.Button_20.Enable = false;
            end
            app.Label_Info.Text = "已导入结果文件！";
        end
        
        function set_button_state(app, state)
            app.Button.Enable = state;
            app.Button_2.Enable = state;
            app.Button_3.Enable = state;
            app.Button_4.Enable = state;

            app.Button_5.Enable = state;
            app.Button_6.Enable = state;
            app.Button_7.Enable = state;
            app.Button_8.Enable = state;

            app.NEDButton.Enable = state;
            app.NEDButton_2.Enable = state;
            app.Button_9.Enable = state;
            app.Button_10.Enable = state;
            app.Button_11.Enable = state;
            app.Button_12.Enable = state;
            app.Button_13.Enable = state;
            app.Button_20.Enable = state;
            
        end
        
        function  compare_laser(app, blh, time, str)
            blh(:, 1) = deg2rad(blh(:, 1));
            blh(:, 2) = deg2rad(blh(:, 2));
            ref_pos = app.refpos';

            [rm, rn] = getRmRn(ref_pos(1));
            h = ref_pos(3);
            DR = diag([rm + h, (rn + h)*cos(ref_pos(1)), -1]);
            head = deg2rad(cell2mat(app.options.initatt));
            head = head(3);
            C = [cos(head), -sin(head), 0; sin(head), cos(head), 0; 0, 0, 1];

            % blh to ned
            pos = zeros(size(blh));
            if ~app.options.isbacklever
                 lever = DRi(app.refpos) * Euler2C(deg2rad(cell2mat(app.options.initatt))) * cell2mat(app.options.antlever);
            end
            
            if app.load_gnss && app.GNSSCheckBox.Value
                gnss_ref = app.refpos + lever;
                gnss = app.gnss_data(:,3:5);
                gnss(:, 1:2) = deg2rad(gnss(:, 1:2));
                gnss_pos = zeros(size(gnss));
                gnssDR = diag([rm + h, (rn + h)*cos(gnss_ref(1)), -1]);
            end
            for i = 1:size(pos, 1)
                delta_blh = blh(i, :) - ref_pos;
                delta_pos = C * DR * delta_blh';
                pos(i, :) = delta_pos';
            end
            pos(:, 3) = -pos(:, 3);
             if app.load_gnss && app.GNSSCheckBox.Value
                 for i=1:size(gnss_pos, 1)
                    delta_blh = gnss(i, :) - gnss_ref';
                    delta_pos = C * gnssDR * delta_blh';
                    gnss_pos(i, :) = delta_pos';
                 end
                gnss_pos(:, 3) = -gnss_pos(:, 3);
            end
            lasertime = app.laser_data(:, 1) - app.options.starttime;
            if app.load_gnss && app.GNSSCheckBox.Value
                gnsstime = app.gnss_data(:, 2) - app.options.starttime;
            end
            laser_disp =  app.laser_data(:, 2:end) -  app.laser_data(1, 2:end);
            laserdata = [];
            data = [];
            gnss_ = [];
            ylabels = [];

            if app.CheckBox.Value
                laserdata = [laserdata,  laser_disp(:, 1)];
                data = [data, pos(:, 1)];
                if app.load_gnss && app.GNSSCheckBox.Value
                    gnss_ = [gnss_, gnss_pos(:, 1)];
                end
                ylabels = [ylabels, "Forward [mm]"];
            end
            if app.CheckBox_2.Value
                laserdata = [laserdata,  laser_disp(:, 2)];
                data = [data, pos(:, 2)];
                if app.load_gnss && app.GNSSCheckBox.Value
                    gnss_ = [gnss_, gnss_pos(:, 2)];
                end
                ylabels = [ylabels, "Right [mm]"];
            end
            if app.CheckBox_3.Value
                laserdata = [laserdata,  laser_disp(:, 3)];
                data = [data, pos(:, 3)];
                if app.load_gnss && app.GNSSCheckBox.Value
                    gnss_ = [gnss_, gnss_pos(:, 3)];
                end
                ylabels = [ylabels, "Down [mm]"];
            end

            figure("Name",str)
            subs=[];
            N = width(data);
            for i = 1:N
                sub = subplot(N, 1, i);
                subs=[subs, sub];
                hold on
                if app.load_gnss && app.GNSSCheckBox.Value
                    plot(gnsstime, gnss_(:, i) * 1e3,"LineWidth",2,"DisplayName","GNSS","Color","#E6AC27");
                end
                plot(lasertime, laserdata(:, i), "LineWidth",2,"DisplayName","Laser","Color","#0C6DB1");
                plot(time, data(:, i) * 1e3, "LineWidth",2,"DisplayName","KF-GINS","Color","#D1521E");
                
                ylabel(ylabels(i))
                grid on
                legend
                hold off
                set(gca, 'FontSize', 20)
            end
            xlabel("Time[s]")
            linkaxes(subs, 'x');

            figure("Name",str + "Err")
            xlabel("Time[s]")
            subs=[];
            N = width(data);
            starttime = max(lasertime(1), time(1));
            endtime = min(lasertime(end), time(end));
            dt = mean(diff(time));
            time_interp = time(time >= starttime & time <= endtime);
            if app.load_gnss && app.GNSSCheckBox.Value
                starttime_gnss = max(lasertime(1), gnsstime(1));
                endtime_gnss = min(lasertime(end), gnsstime(end));
                time_interp_gnss = gnsstime(gnsstime >= starttime_gnss & gnsstime <= endtime_gnss);
            end

            for i = 1:N
                % 内插位移
                res_interp = interp1(time, data(:, i) * 1e3, time_interp);
                ref_interp = interp1(lasertime, laserdata(:, i), time_interp);
                % 位移误差
                disp_error = res_interp - ref_interp;
                % 计算误差统计量
                mean_error = mean(disp_error);      % 单位：mm
                std_error  = std(disp_error);       % 单位：mm
                max_err = max(abs(disp_error));
                sub = subplot(N, 1, i);
                subs=[subs, sub];
                if app.load_gnss && app.GNSSCheckBox.Value
                    % 内插位移
                    res_interp_gnss = interp1(gnsstime, gnss_(:, i) * 1e3, time_interp_gnss);
                    ref_interp_gnss = interp1(lasertime, laserdata(:, i), time_interp_gnss);
                    % 位移误差
                    disp_error_gnss = res_interp_gnss - ref_interp_gnss;
                    % 计算误差统计量
                    mean_error_gnss = mean(disp_error_gnss);      % 单位：mm
                    std_error_gnss  = std(disp_error_gnss);       % 单位：mm
                    max_err_gnss = max(abs(disp_error_gnss));
                    plot(time_interp_gnss, disp_error_gnss, "LineWidth",2,"DisplayName","GNSS");
                    hold on
                end
                plot(time_interp, disp_error, "LineWidth",2,"DisplayName","KF-GINS");
                ylabel("Err " + ylabels(i))
                % 在图上添加文字说明
                str_ = sprintf('Mean = %.3f mm  Std = %.3f mm  Max = %.3f mm', mean_error, std_error, max_err);
                if app.load_gnss && app.GNSSCheckBox.Value
                    str_gnss = sprintf('  -  GNSS: Mean = %.3f mm  Std = %.3f mm  Max = %.3f mm', mean_error_gnss, std_error_gnss, max_err_gnss);
                    str_ = [str_, str_gnss];
                end
                title(str_);
                legend
                grid on
                hold off
                set(gca, 'FontSize', 20)
                % plot_error_distribution(disp_error, "filter")
                if app.load_gnss && app.GNSSCheckBox.Value
                    % plot_error_distribution(disp_error_gnss, "gnss")
                end
            end
            linkaxes(subs, 'x');


        end
        
        function compare_GINS(app, nav, err, std_, time, str)
            pva_time = app.gins_data(:, 2) - app.options.starttime;

            if app.SelectedCompareItems(1)
                res_blh = nav(:, 3:5);
                res_blh(:, 1) = deg2rad(res_blh(:, 1));
                res_blh(:, 2) = deg2rad(res_blh(:, 2));
                pva_blh = app.gins_data(:, 3:5);
                pva_blh(:, 1) = deg2rad(pva_blh(:, 1));
                pva_blh(:, 2) = deg2rad(pva_blh(:, 2));
                ref_pos = app.refpos;

                compare_pos(time, pva_time, res_blh, pva_blh, ref_pos, "Pos GINS - ref" + str);
            end

            if app.SelectedCompareItems(2)
                % velocity
                ylabels=["North [m/s]","East [m/s]","Down [m/s]"];
                compare_result(time, pva_time, nav(:, 6:8), app.gins_data(:, 6:8), "Vel GINS - Ref" + str, ylabels);
            end

            if app.SelectedCompareItems(3)
                % attitude
                ylabels=["Roll [deg]","Pitch [deg]","Yaw [deg]"];
                compare_result(time, pva_time, nav(:, 9:11), app.gins_data(:, 9:11), "Att GINS - Ref" + str, ylabels);
            end

            if app.SelectedCompareItems(4)
                % GyroBias
                ylabels=["X [deg/h]","Y [deg/h]","Z [deg/h]"];
                compare_result(time, pva_time, err(:, 3:5), app.gins_data(:, 12:14), "GyroBias GINS - Ref" + str, ylabels);
            end

            if app.SelectedCompareItems(5)
                % AccelBias
                ylabels=["X [mGal]","Y [mGal]","Z [mGal]"];
                compare_result(time, pva_time, err(:, 6:8), app.gins_data(:, 15:17), "AccelBias GINS - Ref" + str, ylabels);
            end

            if app.SelectedCompareItems(6) && app.options.filtermode == 1
                % GyroScale
                ylabels=["X [ppm]","Y [ppm]","Z [ppm]"];
                compare_result(time, pva_time, err(:, 9:11), app.gins_data(:, 18:20), "GyroScale GINS - Ref" + str, ylabels);
            end
            
             if app.SelectedCompareItems(7) && app.options.filtermode == 1
                % AccelScale
                ylabels=["X [ppm]","Y [ppm]","Z [ppm]"];
                compare_result(time, pva_time, err(:, 12:14), app.gins_data(:, 21:23), "AccelScale GINS - Ref" + str, ylabels);
             end
             
            if app.SelectedCompareItems(8)
                % Pos STD
                ylabels=["North [m]","East [m]","Down [m]"];
                compare_result(time, pva_time, std_(:, 3:5), app.gins_data(:, 24:26), "Pos STD GINS - Ref" + str, ylabels);
            end
            
            if app.SelectedCompareItems(9)
                % Vel STD
                ylabels=["North [m/s]","East [m/s]","Down [m/s]"];
                compare_result(time, pva_time, std_(:, 6:8), app.gins_data(:, 27:29), "Vel STD GINS - Ref" + str, ylabels);
            end

            if app.SelectedCompareItems(10)
                % Att STD
                ylabels=["Roll [deg]","Pitch [deg]","Yaw [deg]"];
                compare_result(time, pva_time, std_(:, 9:11), app.gins_data(:, 30:32), "Att STD GINS - Ref" + str, ylabels);
            end

            if app.SelectedCompareItems(11)
                % GyroBias STD
                ylabels=["X [deg/h]","Y [deg/h]","Z [deg/h]"];
                compare_result(time, pva_time, std_(:, 12:14), app.gins_data(:, 33:35), "GyroBias STD GINS - Ref" + str, ylabels);
            end

            if app.SelectedCompareItems(12)
                % AccelBias STD
                ylabels=["X [mGal]","Y [mGal]","Z [mGal]"];
                compare_result(time, pva_time, std_(:, 15:17), app.gins_data(:, 36:38), "AccelBias STD GINS - Ref" + str, ylabels);
            end

            if app.SelectedCompareItems(13) && app.options.filtermode == 1
                % GyroScale STD
                ylabels=["X [ppm]","Y [ppm]","Z [ppm]"];
                compare_result(time, pva_time, std_(:, 18:20), app.gins_data(:, 39:41), "GyroScale STD GINS - Ref" + str, ylabels);
            end
            
             if app.SelectedCompareItems(14) && app.options.filtermode == 1
                % AccelScale STD
                ylabels=["X [ppm]","Y [ppm]","Z [ppm]"];
                compare_result(time, pva_time, std_(:, 21:23), app.gins_data(:, 42:44), "AccelScale STD GINS - Ref" + str, ylabels);
             end
        end

        function set_status_text(app, str)
            app.Label_Info.Text = str;
        end
        
        function export_all_figures(app)
            % 选择导出文件夹
            exportDir = uigetdir(pwd, '选择导出文件夹');
            if exportDir == 0
                app.set_status_text('用户取消了操作');
                return;
            end

            % 选择导出格式
            [indx, tf] = listdlg('PromptString', '选择导出格式', ...
                'SelectionMode', 'single', ...
                'ListString', {'fig', 'png'}, ...
                'InitialValue', 1);
            if ~tf
                app.set_status_text('用户取消了操作');
                return;
            end
            formats = {'fig', 'png'};
            exportFormat = formats{indx};

            % 获取所有 figure，排除 app.UIFigure
            figHandles = findall(0, 'Type', 'figure');
            figHandles = figHandles(~strcmp(get(figHandles, 'Name'), "GINS-Plot"));
            figHandles = figHandles(~strcmp(get(figHandles, 'Name'), "Gins Compare 设置窗口"));
            if isempty(figHandles)
                app.set_status_text('没有找到可导出的 figure');
                return;
            end

            % 遍历导出
            total = length(figHandles);
            for i = 1:total
                fig = figHandles(i);

                % 获取 figure 名称
                figName = get(fig, 'Name');
                if isempty(figName)
                    figName = sprintf('Figure_%d', fig.Number);
                end
                figName = regexprep(figName, '[\/:*?"<>|]', '_'); % 替换非法字符

                % 检查是否重名
                fullPath = fullfile(exportDir, [figName, '.', exportFormat]);
                count = 1;
                while isfile(fullPath)
                    fullPath = fullfile(exportDir, sprintf('%s_%d.%s', figName, count, exportFormat));
                    count = count + 1;
                end

                % 保存
                switch exportFormat
                    case 'fig'
                        savefig(fig, fullPath);
                    case 'png'
                        exportgraphics(fig, fullPath, 'Resolution', 600); % 原尺寸 600 DPI
                end

                % 在 App 状态栏显示进度
                app.set_status_text(sprintf('已导出 %d/%d: %s', i, total, fullPath));
                drawnow; % 确保 UI 更新
            end

            app.set_status_text('所有 figure 已导出完成。');
        end

        function onConfirmed(app, ginsCmp, dfig)
            % 先判断窗口是否有效
            if isvalid(dfig)
                app.SelectedCompareItems = ginsCmp.getSelectedOptions();
                str_ = "";
                if app.options.userts && app.RTSCheckBox.Value
                    time = app.run_time_rts;
                    nav = app.navdata_rts;
                    err = app.imu_err_rts;
                    std_ = app.std_rts;
                    str = str_ + "_RTS";
                    app.compare_GINS(nav, err, std_, time, str);
                end
                if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                    nav = app.fixedlag_data;
                    time = app.fixedlag_time;
                    err = app.fixedlag_imu_err;
                    std_ = app.fixedlag_std;
                    str = str_ + "_Fixed-lag";
                    app.compare_GINS(nav, err, std_, time, str);
                end
                if app.FIlter.Value
                    time = app.run_time;
                    nav = app.navdata;
                    err = app.imu_err;
                    std_ = app.std_filter;
                    str = str_;
                    app.compare_GINS(nav, err, std_, time, str);
                end
            else
                app.set_status_text("选择出错！");
            end
        end

end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("icons")
            app.UIFigure.Name = "GINS-Plot";
            app.UIFigure.Icon = "icons\icon.png";
            addpath("functions")
            app.set_button_state(false);
            app.Button_18.Enable = false;
            app.load_gnss = false;
            app.load_result = false;
            app.load_yaml = false;
            
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            close all;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            % 打开文件夹选择对话框
                folder_path = uigetdir('', '选择结果文件夹');

                % 如果用户选择了文件夹（不是点击取消）
                if folder_path ~= 0
                    % 设置 EditField_result_path 的文本为所选文件夹路径
                    app.EditField_result_path.Value = folder_path;
                end

                app.load_result = true;

                if app.load_yaml
                    app.load_results();
                end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            % 打开文件选择对话框，只显示 .yaml 文件
            [file, path] = uigetfile({'*.yaml', 'YAML Files (*.yaml)'}, '选择 YAML 文件');

            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);

            % 设置 EditField_result_path 的文本为所选文件路径
            app.EditField_yaml_path.Value = fullpath;
            app.options =  yaml.loadFile(fullpath);

            app.load_yaml = true;

            if app.load_result
                app.load_results;
            end
        end

        % Value changed function: EditField_result_path
        function EditField_result_pathValueChanged(app, event)
            app.load_result = true;

            if app.load_yaml
                app.load_results();
            end
        end

        % Value changed function: EditField_yaml_path
        function EditField_yaml_pathValueChanged(app, event)
            value = app.EditField_yaml_path.Value;
            app.options =  yaml.loadFile(value);

            app.load_yaml = true;

            if app.load_result
                app.load_results;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            str_ = "Trajectory";
            if app.options.userts && app.RTSCheckBox.Value
                blh = app.navdata_rts(:, 3:5);
                str = str_ + "_RTS";
                plot_traj(blh, str, app.refpos', app.run_time_rts);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                blh = app.fixedlag_data(:, 3:5);
                str = str_ + "_Fixed-lag";
                plot_traj(blh, str, app.refpos', app.fixedlag_time);
            end
            if app.FIlter.Value
                blh = app.navdata(:, 3:5);
                str = str_;
                plot_traj(blh, str, app.refpos', app.run_time);
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            str_ = "Height";
            if app.options.userts && app.RTSCheckBox.Value
                height = app.navdata_rts(:, 5);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_height(time, height, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                height = app.fixedlag_data(:, 5);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_height(time, height, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                height = app.navdata(:, 5);
                str = str_;
                plot_height(time, height, str);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            str_ = "Velocity";
            if app.options.userts && app.RTSCheckBox.Value
                vel = app.navdata_rts(:, 6:8);
                str = str_ + "_RTS";
                time = app.run_time_rts;
                plot_vel(time, vel, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                vel = app.fixedlag_data(:, 6:8);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_vel(time, vel, str);
            end
            if app.FIlter.Value
                vel = app.navdata(:, 6:8);
                time = app.run_time;
                str = str_;
                plot_vel(time, vel, str);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            str_ = "Attitude";
            if app.options.userts && app.RTSCheckBox.Value
                att = app.navdata_rts(:, 9:11);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_att(time, att, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                att = app.fixedlag_data(:, 9:11);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_att(time, att, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                att = app.navdata(:, 9:11);
                str = str_;
                plot_att(time, att, str);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            str_ = "GyroBias";
            if app.options.userts && app.RTSCheckBox.Value
                gb = app.imu_err_rts(:, 3:5);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_gyro_bias(time, gb, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                gb = app.fixedlag_imu_err(:, 3:5);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_gyro_bias(time, gb, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                gb = app.imu_err(:, 3:5);
                str = str_;
                plot_gyro_bias(time, gb, str);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            str_ = "AccelBias";
            if app.options.userts && app.RTSCheckBox.Value
                ab = app.imu_err_rts(:, 6:8);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_acc_bias(time, ab, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                ab = app.fixedlag_imu_err(:, 6:8);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_acc_bias(time, ab, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                ab = app.imu_err(:, 6:8);
                str = str_;
                plot_acc_bias(time, ab, str);
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.options.filtermode == 2
                % 弹出警告对话框
                d = warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
                % 等待用户关闭警告窗口
                uiwait(d);
            end
            str_ = "GyroScale";
            if app.options.userts && app.RTSCheckBox.Value
                gs = app.imu_err_rts(:, 9:11);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_gyro_scale(time, gs, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                gs = app.fixedlag_imu_err(:, 9:11);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_gyro_scale(time, gs, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                gs = app.imu_err(:, 9:11);
                str = str_;
                plot_gyro_scale(time, gs, str);
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.options.filtermode == 2
                % 弹出警告对话框
                d = warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
                % 等待用户关闭警告窗口
                uiwait(d);
            end
            str_ = "AccelScale";
            if app.options.userts && app.RTSCheckBox.Value
                as = app.imu_err_rts(:, 12:14);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_acc_scale(time, as, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                as = app.fixedlag_imu_err(:, 12:14);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_acc_scale(time, as, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                as = app.imu_err(:, 12:14);
                str = str_;
                plot_acc_scale(time, as, str);
            end
            plot_acc_scale(time, as, str);
        end

        % Button pushed function: NEDButton
        function NEDButtonPushed(app, event)
            str_ = "Pos STD";
            if app.options.userts && app.RTSCheckBox.Value
                posstd = app.std_rts(:, 3:5);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_pos_std(time, posstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                posstd = app.fixedlag_std(:, 3:5);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_pos_std(time, posstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                posstd = app.std_filter(:, 3:5);
                str = str_;
                plot_pos_std(time, posstd, str);
            end
        end

        % Button pushed function: NEDButton_2
        function NEDButton_2Pushed(app, event)
            str_ = "Vel STD";
            if app.options.userts && app.RTSCheckBox.Value
                velstd = app.std_rts(:, 6:8);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_vel_std(time, velstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                velstd = app.fixedlag_std(:, 6:8);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_vel_std(time, velstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                velstd = app.std_filter(:, 6:8);
                str = str_;
                plot_vel_std(time, velstd, str);
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            str_ = "Att STD";
            if app.options.userts && app.RTSCheckBox.Value
                attstd = app.std_rts(:, 9:11);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_att_std(time, attstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                attstd = app.fixedlag_std(:, 9:11);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_att_std(time, attstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                attstd = app.std_filter(:, 9:11);
                str = str_;
                plot_att_std(time, attstd, str);
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            str_ = "Gyro Bias STD";
            if app.options.userts && app.RTSCheckBox.Value
                gbstd = app.std_rts(:, 12:14);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_gyrobias_std(time, gbstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                gbstd = app.fixedlag_std(:, 12:14);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_gyrobias_std(time, gbstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                gbstd = app.std_filter(:, 12:14);
                str = str_;
                plot_gyrobias_std(time, gbstd, str);
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            str_ = "Acc Bias STD";
            if app.options.userts && app.RTSCheckBox.Value
                abstd = app.std_rts(:, 15:17);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                plot_accbias_std(time, abstd, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                abstd = app.fixedlag_std(:, 15:17);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_accbias_std(time, abstd, str);
            end
            if app.FIlter.Value
                time = app.run_time;
                abstd = app.std_filter(:, 15:17);
                str = str_;
                plot_accbias_std(time, abstd, str);
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if app.options.filtermode == 2
                % 弹出警告对话框
                warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
            elseif app.options.filtermode == 1
                str_ = "GyroScale STD";
                if app.options.userts && app.RTSCheckBox.Value
                    gsstd = app.std_rts(:, 18:20);
                    time = app.run_time_rts;
                    str = str_ + "_RTS";
                    plot_gyroscale_std(time, gsstd, str);
                end
                if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                    gsstd = app.fixedlag_std(:, 18:20);
                    time = app.fixedlag_time;
                    str = str_ + "_Fixed-lag";
                    plot_gyroscale_std(time, gsstd, str);
                end
                if app.FIlter.Value
                    time = app.run_time;
                    gsstd = app.std_filter(:, 18:20);
                    str = str_;
                    plot_gyroscale_std(time, gsstd, str);
                end
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            if app.options.filtermode == 2
                % 弹出警告对话框
                warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
            elseif app.options.filtermode ==1
                str_ = "AccelScale STD";
                if app.options.userts && app.RTSCheckBox.Value
                    asstd = app.std_rts(:, 21:23);
                    time = app.run_time_rts;
                    str = str_ + "_RTS";
                    plot_gyroscale_std(time, asstd, str);
                end
                if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                    asstd = app.fixedlag_std(:, 21:23);
                    time = app.fixedlag_time;
                    str = str_ + "_Fixed-lag";
                    plot_gyroscale_std(time, asstd, str);
                end
                if app.FIlter.Value
                    time = app.run_time;
                    asstd = app.std_filter(:, 21:23);
                    str = str_;
                    plot_gyroscale_std(time, asstd, str);
                end
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            [file, path] = uigetfile({'*.txt', 'Laser Files (*.txt)'}, '选择 Txt 文件');

            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);

            % 设置文本为所选文件路径
            app.EditField_laser_path.Value = fullpath;
            app.laser_data = importdata(fullpath);

            if app.load_result && app.load_yaml
                app.laser_data = app.laser_data(app.laser_data(:, 1)>app.options.starttime, :);
                app.Button_18.Enable =true;
            end
            app.set_status_text("导入激光参考值！");
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            % position
            str_ = "Laser - Ref";
            if app.options.userts && app.RTSCheckBox.Value
                blh = app.navdata_rts(:, 3:5);
                time = app.run_time_rts;
                str = str_ + "_RTS";
                app.compare_laser(blh, time, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                blh = app.fixedlag_data(:, 3:5);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                app.compare_laser(blh, time, str);
            end
            if app.FIlter.Value
                blh = app.navdata(:, 3:5);
                time = app.run_time;
                str = str_;
                app.compare_laser(blh, time, str);
            end
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            if ~app.load_result || ~app.load_yaml
                % 弹出警告对话框
                d = warndlg('未导入结果文件！', '警告');
                % 等待用户关闭警告窗口
                uiwait(d);
                return
            end
            [file, path] = uigetfile({'*.bin', 'GINS Files (*.bin)'}, '选择 BIN 文件');

            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);

            % 设置文本为所选文件路径
            app.EditField_gins_path.Value = fullpath;
            app.readGinsPav(fullpath);
            app.set_status_text("导入GINS参考值！")

            if app.load_result && app.load_yaml
                app.gins_data = app.gins_data(app.gins_data(:, 2)>app.options.starttime, :);
                % 新建一个独立窗口
                dfig = uifigure('Name', 'Gins Compare 设置窗口', 'Position', [100 100 620 400]);

                % 创建组件，父容器是新窗口
                ginsCmp = Gins_compare(dfig);
                ginsCmp.Position = [10 10 595 360]; % 组件位置大小适配新窗口

                % 监听组件的 Confirmed 事件
                addlistener(ginsCmp, 'Confirmed', @(src, evnt) onConfirmed(app, src, dfig));
            end
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            app.set_button_state(false);
            app.EditField_gins_path.Value = "";
            app.EditField_laser_path.Value = "";
            app.EditField_result_path.Value = "";
            app.EditField_yaml_path.Value = "";
            app.EditField_gnss_path.Value = "";
            app.load_gnss = false;
            app.load_result = false;
            app.load_yaml = false;
            app.Button_18.Enable = false;

            app.RTSCheckBox.Value = false;
            app.RTSCheckBox.Enable = true;
            app.Fixed_lagCheckBox.Value = false;
            app.Fixed_lagCheckBox.Enable = true;

            app.Label_Info.Text = "";
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            app.options =  yaml.loadFile(app.EditField_yaml_path.Value);
            app.load_results;
        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            app.export_all_figures;
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            % 打开文件选择对话框，只显示 .txt .pos 文件
            [file, path] = uigetfile({'*.pos;*.txt', 'Pos or Text Files (*.pos, *.txt)'}, '选择 Pos 或 Txt 文件');


            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);
            app.EditField_gnss_path.Value = fullpath;
            app.gnss_data = readmatrix(fullpath,'FileType','text');
            app.load_gnss = true;
            app.Label_Info.Text = "已导入GNSS文件！";
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            str_ = "Acceleration";
            if app.options.isoutimu && app.RTSCheckBox.Value
                acc = app.rtsimu(:, 9:11);
                str = str_ + "_RTS";
                time = app.run_time_rts;
                plot_acc(time, acc, str);
            end
            if app.options.usefixedlag && app.Fixed_lagCheckBox.Value
                acc = app.flimu(:, 9:11);
                time = app.fixedlag_time;
                str = str_ + "_Fixed-lag";
                plot_acc(time, acc, str);
            end
            if app.FIlter.Value
                acc = app.imu(:, 9:11);
                time = app.run_time;
                str = str_;
                plot_acc(time, acc, str);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'关闭所有图片'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icons', '关闭.png');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'清除文件'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icons', '清除文件按钮.png');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'重新导入文件'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'icons', '读取文件所有文本内容.png');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'保存图片'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'icons', '保存图片-copy.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '   绘图选项';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '导航状态';
            app.Button_2 = uibutton(app.GridLayout4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '高程';
            app.Button_3 = uibutton(app.GridLayout4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '速度';
            app.Button_4 = uibutton(app.GridLayout4, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '姿态';
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '轨迹';
            app.GridLayout4_2 = uigridlayout(app.GridLayout3);
            app.Label_2 = uilabel(app.GridLayout4_2);
            app.Label_2.Text = '惯导误差';
            app.Button_5 = uibutton(app.GridLayout4_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '陀螺零偏';
            app.Button_6 = uibutton(app.GridLayout4_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '加表零偏';
            app.Button_7 = uibutton(app.GridLayout4_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '陀螺比例因子';
            app.Button_8 = uibutton(app.GridLayout4_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '加表比例因子';
            app.GridLayout4_3 = uigridlayout(app.GridLayout3);
            app.Label_3 = uilabel(app.GridLayout4_3);
            app.Label_3.Text = '标准差';
            app.NEDButton = uibutton(app.GridLayout4_3, 'push');
            app.NEDButton.ButtonPushedFcn = createCallbackFcn(app, @NEDButtonPushed, true);
            app.NEDButton.Text = 'NED位置';
            app.NEDButton_2 = uibutton(app.GridLayout4_3, 'push');
            app.NEDButton_2.ButtonPushedFcn = createCallbackFcn(app, @NEDButton_2Pushed, true);
            app.NEDButton_2.Text = 'NED速度';
            app.Button_9 = uibutton(app.GridLayout4_3, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '姿态';
            app.Button_10 = uibutton(app.GridLayout4_3, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '陀螺零偏';
            app.Button_11 = uibutton(app.GridLayout4_3, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '加表零偏';
            app.Button_12 = uibutton(app.GridLayout4_3, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '陀螺比例因子';
            app.Button_13 = uibutton(app.GridLayout4_3, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '加表比例因子';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.RTSCheckBox = uicheckbox(app.GridLayout6);
            app.RTSCheckBox.Text = 'RTS';
            app.Fixed_lagCheckBox = uicheckbox(app.GridLayout6);
            app.Fixed_lagCheckBox.Text = 'Fixed_lag';
            app.FIlter = uicheckbox(app.GridLayout6);
            app.FIlter.Text = 'Filter';
            app.Button_20 = uibutton(app.GridLayout6, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '加速度';
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '   文件路径';
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '结果文件路径';
            app.EditField_result_path = uieditfield(app.GridLayout2, 'text');
            app.EditField_result_path.ValueChangedFcn = createCallbackFcn(app, @EditField_result_pathValueChanged, true);
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '配置文件路径';
            app.EditField_yaml_path = uieditfield(app.GridLayout2, 'text');
            app.EditField_yaml_path.ValueChangedFcn = createCallbackFcn(app, @EditField_yaml_pathValueChanged, true);
            app.Button_14 = uibutton(app.GridLayout2, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.GridLayout2, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            app.Button_15.Text = '';
            app.Button_19 = uibutton(app.GridLayout2, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            app.Button_19.Text = '';
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = 'GNSS文件路径';
            app.EditField_gnss_path = uieditfield(app.GridLayout2, 'text');
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '   参考值';
            app.GridLayout2_2 = uigridlayout(app.Panel_3);
            app.Label_6 = uilabel(app.GridLayout2_2);
            app.Label_6.Text = '激光参考值路径';
            app.EditField_laser_path = uieditfield(app.GridLayout2_2, 'text');
            app.Label_7 = uilabel(app.GridLayout2_2);
            app.Label_7.Text = 'GINS参考值路径';
            app.EditField_gins_path = uieditfield(app.GridLayout2_2, 'text');
            app.Button_16 = uibutton(app.GridLayout2_2, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.GridLayout2_2, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            app.Button_17.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2_2);
            app.CheckBox = uicheckbox(app.GridLayout5);
            app.CheckBox.Text = '前向';
            app.CheckBox_2 = uicheckbox(app.GridLayout5);
            app.CheckBox_2.Text = '侧向';
            app.CheckBox_3 = uicheckbox(app.GridLayout5);
            app.CheckBox_3.Text = '垂向';
            app.Button_18 = uibutton(app.GridLayout2_2, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '绘制';
            app.GNSSCheckBox = uicheckbox(app.GridLayout2_2);
            app.GNSSCheckBox.Text = 'GNSS';
            app.Panel_4 = uipanel(app.GridLayout);
            app.Label_Info = uilabel(app.Panel_4);
            app.Label_Info.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GINSPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end