classdef Gins_compare < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        Panel_3         matlab.ui.container.Panel
        GridLayout3     matlab.ui.container.GridLayout
        Button_confirm  matlab.ui.control.Button
        Button_cancel   matlab.ui.control.Button
        Panel_2         matlab.ui.container.Panel
        GridLayout2_2   matlab.ui.container.GridLayout
        CheckBox_16     matlab.ui.control.CheckBox
        CheckBox_15     matlab.ui.control.CheckBox
        CheckBox_14     matlab.ui.control.CheckBox
        CheckBox_13     matlab.ui.control.CheckBox
        CheckBox_12     matlab.ui.control.CheckBox
        CheckBox_11     matlab.ui.control.CheckBox
        CheckBox_10     matlab.ui.control.CheckBox
        CheckBox_9      matlab.ui.control.CheckBox
        Panel           matlab.ui.container.Panel
        GridLayout2     matlab.ui.container.GridLayout
        CheckBox_8      matlab.ui.control.CheckBox
        CheckBox_7      matlab.ui.control.CheckBox
        CheckBox_6      matlab.ui.control.CheckBox
        CheckBox_5      matlab.ui.control.CheckBox
        CheckBox_4      matlab.ui.control.CheckBox
        CheckBox_3      matlab.ui.control.CheckBox
        CheckBox_2      matlab.ui.control.CheckBox
        CheckBox        matlab.ui.control.CheckBox
    end

    % 具有关联的公共回调的事件
    events (HasCallbackProperty, NotifyAccess = private)
        Confirmed
    end

    
    methods (Access = private)

    end
    
    methods (Access = public)

        function selectedList = getSelectedOptions(comp)
            % 汇总所有复选框的文本和状态
            checkboxes = { ...
                comp.CheckBox, comp.CheckBox_2, comp.CheckBox_3, comp.CheckBox_4, ...
                comp.CheckBox_5, comp.CheckBox_6, comp.CheckBox_7, ...
                comp.CheckBox_9, comp.CheckBox_10, comp.CheckBox_11, comp.CheckBox_12, ...
                comp.CheckBox_13, comp.CheckBox_14, comp.CheckBox_15};

            selectedList = [];
            for i = 1:numel(checkboxes)
                selectedList(i) = checkboxes{i}.Value; %#ok<AGROW>
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
        end

        % Button pushed function: Button_confirm
        function Button_confirmPushed(comp, event)
            notify(comp, 'Confirmed');
        end

        % Button pushed function: Button_cancel
        function Button_cancelPushed(comp, event)
            delete(comp.Parent);
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(comp, event)
            value = comp.CheckBox_8.Value;
            checkboxes = { ...
                comp.CheckBox, comp.CheckBox_2, comp.CheckBox_3, comp.CheckBox_4, ...
                comp.CheckBox_5, comp.CheckBox_6, comp.CheckBox_7};
            for i = 1:numel(checkboxes)
                checkboxes{i}.Value = value;
            end

        end

        % Value changed function: CheckBox_16
        function CheckBox_16ValueChanged(comp, event)
            value = comp.CheckBox_16.Value;
            checkboxes = { ...
                comp.CheckBox_9, comp.CheckBox_10, comp.CheckBox_11, comp.CheckBox_12, ...
                comp.CheckBox_13, comp.CheckBox_14, comp.CheckBox_15};
            for i = 1:numel(checkboxes)
                checkboxes{i}.Value = value;
            end
        end
    end

    methods (Access = protected)
        
        % 属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
            
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 595 360];
            comp.HandleVisibility = 'on';

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.RowHeight = {'1x', '0.2x'};

            % Create Panel
            comp.Panel = uipanel(comp.GridLayout);
            comp.Panel.TitlePosition = 'centertop';
            comp.Panel.Title = '导航状态';
            comp.Panel.Layout.Row = 1;
            comp.Panel.Layout.Column = 1;
            comp.Panel.FontName = '华文中宋';
            comp.Panel.FontWeight = 'bold';
            comp.Panel.FontSize = 24;

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.Panel);
            comp.GridLayout2.ColumnWidth = {'1x', '2x'};
            comp.GridLayout2.RowHeight = {'1x', '1x', '1x', '1x'};

            % Create CheckBox
            comp.CheckBox = uicheckbox(comp.GridLayout2);
            comp.CheckBox.Text = '位置';
            comp.CheckBox.FontName = '华文中宋';
            comp.CheckBox.FontSize = 24;
            comp.CheckBox.Layout.Row = 1;
            comp.CheckBox.Layout.Column = 1;

            % Create CheckBox_2
            comp.CheckBox_2 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_2.Text = '速度';
            comp.CheckBox_2.FontName = '华文中宋';
            comp.CheckBox_2.FontSize = 24;
            comp.CheckBox_2.Layout.Row = 2;
            comp.CheckBox_2.Layout.Column = 1;

            % Create CheckBox_3
            comp.CheckBox_3 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_3.Text = '姿态';
            comp.CheckBox_3.FontName = '华文中宋';
            comp.CheckBox_3.FontSize = 24;
            comp.CheckBox_3.Layout.Row = 3;
            comp.CheckBox_3.Layout.Column = 1;

            % Create CheckBox_4
            comp.CheckBox_4 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_4.Text = '陀螺零偏';
            comp.CheckBox_4.FontName = '华文中宋';
            comp.CheckBox_4.FontSize = 24;
            comp.CheckBox_4.Layout.Row = 1;
            comp.CheckBox_4.Layout.Column = 2;

            % Create CheckBox_5
            comp.CheckBox_5 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_5.Text = '加表零偏';
            comp.CheckBox_5.FontName = '华文中宋';
            comp.CheckBox_5.FontSize = 24;
            comp.CheckBox_5.Layout.Row = 2;
            comp.CheckBox_5.Layout.Column = 2;

            % Create CheckBox_6
            comp.CheckBox_6 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_6.Text = '陀螺比例因子';
            comp.CheckBox_6.FontName = '华文中宋';
            comp.CheckBox_6.FontSize = 24;
            comp.CheckBox_6.Layout.Row = 3;
            comp.CheckBox_6.Layout.Column = 2;

            % Create CheckBox_7
            comp.CheckBox_7 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_7.Text = '加表比例因子';
            comp.CheckBox_7.FontName = '华文中宋';
            comp.CheckBox_7.FontSize = 24;
            comp.CheckBox_7.Layout.Row = 4;
            comp.CheckBox_7.Layout.Column = 2;

            % Create CheckBox_8
            comp.CheckBox_8 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_8.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_8ValueChanged, true);
            comp.CheckBox_8.Text = '全选';
            comp.CheckBox_8.FontName = '华文中宋';
            comp.CheckBox_8.FontSize = 24;
            comp.CheckBox_8.Layout.Row = 4;
            comp.CheckBox_8.Layout.Column = 1;

            % Create Panel_2
            comp.Panel_2 = uipanel(comp.GridLayout);
            comp.Panel_2.TitlePosition = 'centertop';
            comp.Panel_2.Title = '方差';
            comp.Panel_2.Layout.Row = 1;
            comp.Panel_2.Layout.Column = 2;
            comp.Panel_2.FontName = '华文中宋';
            comp.Panel_2.FontWeight = 'bold';
            comp.Panel_2.FontSize = 24;

            % Create GridLayout2_2
            comp.GridLayout2_2 = uigridlayout(comp.Panel_2);
            comp.GridLayout2_2.ColumnWidth = {'1x', '2x'};
            comp.GridLayout2_2.RowHeight = {'1x', '1x', '1x', '1x'};

            % Create CheckBox_9
            comp.CheckBox_9 = uicheckbox(comp.GridLayout2_2);
            comp.CheckBox_9.Text = '位置';
            comp.CheckBox_9.FontName = '华文中宋';
            comp.CheckBox_9.FontSize = 24;
            comp.CheckBox_9.Layout.Row = 1;
            comp.CheckBox_9.Layout.Column = 1;

            % Create CheckBox_10
            comp.CheckBox_10 = uicheckbox(comp.GridLayout2_2);
            comp.CheckBox_10.Text = '速度';
            comp.CheckBox_10.FontName = '华文中宋';
            comp.CheckBox_10.FontSize = 24;
            comp.CheckBox_10.Layout.Row = 2;
            comp.CheckBox_10.Layout.Column = 1;

            % Create CheckBox_11
            comp.CheckBox_11 = uicheckbox(comp.GridLayout2_2);
            comp.CheckBox_11.Text = '姿态';
            comp.CheckBox_11.FontName = '华文中宋';
            comp.CheckBox_11.FontSize = 24;
            comp.CheckBox_11.Layout.Row = 3;
            comp.CheckBox_11.Layout.Column = 1;

            % Create CheckBox_12
            comp.CheckBox_12 = uicheckbox(comp.GridLayout2_2);
            comp.CheckBox_12.Text = '陀螺零偏';
            comp.CheckBox_12.FontName = '华文中宋';
            comp.CheckBox_12.FontSize = 24;
            comp.CheckBox_12.Layout.Row = 1;
            comp.CheckBox_12.Layout.Column = 2;

            % Create CheckBox_13
            comp.CheckBox_13 = uicheckbox(comp.GridLayout2_2);
            comp.CheckBox_13.Text = '加表零偏';
            comp.CheckBox_13.FontName = '华文中宋';
            comp.CheckBox_13.FontSize = 24;
            comp.CheckBox_13.Layout.Row = 2;
            comp.CheckBox_13.Layout.Column = 2;

            % Create CheckBox_14
            comp.CheckBox_14 = uicheckbox(comp.GridLayout2_2);
            comp.CheckBox_14.Text = '陀螺比例因子';
            comp.CheckBox_14.FontName = '华文中宋';
            comp.CheckBox_14.FontSize = 24;
            comp.CheckBox_14.Layout.Row = 3;
            comp.CheckBox_14.Layout.Column = 2;

            % Create CheckBox_15
            comp.CheckBox_15 = uicheckbox(comp.GridLayout2_2);
            comp.CheckBox_15.Text = '加表比例因子';
            comp.CheckBox_15.FontName = '华文中宋';
            comp.CheckBox_15.FontSize = 24;
            comp.CheckBox_15.Layout.Row = 4;
            comp.CheckBox_15.Layout.Column = 2;

            % Create CheckBox_16
            comp.CheckBox_16 = uicheckbox(comp.GridLayout2_2);
            comp.CheckBox_16.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_16ValueChanged, true);
            comp.CheckBox_16.Text = '全选';
            comp.CheckBox_16.FontName = '华文中宋';
            comp.CheckBox_16.FontSize = 24;
            comp.CheckBox_16.Layout.Row = 4;
            comp.CheckBox_16.Layout.Column = 1;

            % Create Panel_3
            comp.Panel_3 = uipanel(comp.GridLayout);
            comp.Panel_3.TitlePosition = 'righttop';
            comp.Panel_3.Layout.Row = 2;
            comp.Panel_3.Layout.Column = [1 2];

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.Panel_3);
            comp.GridLayout3.ColumnWidth = {'1x', '1x', '1x', '1x'};
            comp.GridLayout3.RowHeight = {'1x'};
            comp.GridLayout3.ColumnSpacing = 40;

            % Create Button_cancel
            comp.Button_cancel = uibutton(comp.GridLayout3, 'push');
            comp.Button_cancel.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_cancelPushed, true);
            comp.Button_cancel.FontName = '华文中宋';
            comp.Button_cancel.FontSize = 20;
            comp.Button_cancel.Layout.Row = 1;
            comp.Button_cancel.Layout.Column = 3;
            comp.Button_cancel.Text = '完成';

            % Create Button_confirm
            comp.Button_confirm = uibutton(comp.GridLayout3, 'push');
            comp.Button_confirm.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_confirmPushed, true);
            comp.Button_confirm.FontName = '华文中宋';
            comp.Button_confirm.FontSize = 20;
            comp.Button_confirm.Layout.Row = 1;
            comp.Button_confirm.Layout.Column = 4;
            comp.Button_confirm.Text = '绘图';
            
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end