classdef plots < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout             matlab.ui.container.GridLayout
        Panel_3                matlab.ui.container.Panel
        GridLayout2_2          matlab.ui.container.GridLayout
        GridLayout5_2          matlab.ui.container.GridLayout
        CheckBox_3             matlab.ui.control.CheckBox
        CheckBox_2             matlab.ui.control.CheckBox
        CheckBox               matlab.ui.control.CheckBox
        Button_24              matlab.ui.control.Button
        Button_23              matlab.ui.control.Button
        GridLayout5            matlab.ui.container.GridLayout
        CheckBox_6             matlab.ui.control.CheckBox
        CheckBox_5             matlab.ui.control.CheckBox
        CheckBox_4             matlab.ui.control.CheckBox
        Button_22              matlab.ui.control.Button
        Button_21              matlab.ui.control.Button
        EditField_gins_path    matlab.ui.control.EditField
        Label_7                matlab.ui.control.Label
        EditField_laser_path   matlab.ui.control.EditField
        Label_6                matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        EditField_yaml_path    matlab.ui.control.EditField
        Label_2                matlab.ui.control.Label
        EditField_result_path  matlab.ui.control.EditField
        Label                  matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        RTSCheckBox            matlab.ui.control.CheckBox
        GridLayout4_3          matlab.ui.container.GridLayout
        Button_20              matlab.ui.control.Button
        Button_19              matlab.ui.control.Button
        Button_18              matlab.ui.control.Button
        Button_17              matlab.ui.control.Button
        Button_15              matlab.ui.control.Button
        NEDButton_2            matlab.ui.control.Button
        NEDButton              matlab.ui.control.Button
        Label_5                matlab.ui.control.Label
        GridLayout4_2          matlab.ui.container.GridLayout
        Button_12              matlab.ui.control.Button
        Button_11              matlab.ui.control.Button
        Button_10              matlab.ui.control.Button
        Button_9               matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        GridLayout4            matlab.ui.container.GridLayout
        Button_8               matlab.ui.control.Button
        Button_7               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        Label_3                matlab.ui.control.Label
        Button_3               matlab.ui.control.Button
    end

    properties (Access = private)
        D2R double = 0.017453292519943;
        gins_data double
        imu_err double
        imu_err_rts double
        laser_data double
        load_result logical = false;
        load_yaml logical = false;
        navdata double
        navdata_rts
        options % 配置文件
        refpos double
        run_time double
        run_time_rts double
        std double
        std_rts double
    end
    
    
    methods (Access = private)
               
        function readGinsPav(comp, fname)
            fid2 = fopen(fname, 'r');
            fread(fid2, 256, 'double');
            [data, ~] = fread(fid2, [17,inf], 'double');
            data = data';
            data = data(:,2:11);
            if mean(diff(data(:,1))) < 0
                data = flipud(data);
            end
            clear temp;
            fclose(fid2);
            comp.gins_data = data;
        end

        function load_results(comp)
            path = comp.EditField_result_path.Value;
            comp.navdata = importdata(path + "/KF_GINS_Navresult.nav");
            comp.imu_err = importdata(path + "/KF_GINS_IMU_ERR.txt");
            comp.std = importdata(path + "/KF_GINS_STD.txt");

            starttime = comp.options.starttime;
            endtime = min([comp.navdata(end,2), comp.imu_err(end,2), comp.std(end,2)]);
            comp.navdata = comp.navdata(comp.navdata(:, 2)>=starttime & comp.navdata(:, 2)<=endtime , :);
            comp.imu_err = comp.imu_err(comp.imu_err(:, 2)>=starttime & comp.imu_err(:, 2)<=endtime , :);
            comp.std = comp.std(comp.std(:, 2)>=starttime & comp.std(:, 2)<=endtime , :);
            comp.run_time = comp.navdata(:, 2) - starttime;

            if comp.options.userts
                comp.navdata_rts = importdata(path + "/KF_GINS_SM_Navresult.nav");
                comp.imu_err_rts = importdata(path + "/KF_GINS_SM_IMU_ERR.txt");
                comp.std_rts = importdata(path + "/KF_GINS_SM_STD.txt");
                endtime = min([comp.navdata_rts(end,2), comp.imu_err_rts(end,2), comp.std_rts(end,2)]);
                comp.navdata_rts = comp.navdata_rts(comp.navdata_rts(:, 2)>=starttime & comp.navdata_rts(:, 2)<=endtime , :);
                comp.imu_err_rts = comp.imu_err_rts(comp.imu_err_rts(:, 2)>=starttime & comp.imu_err_rts(:, 2)<=endtime , :);
                comp.std_rts = comp.std_rts(comp.std_rts(:, 2)>=starttime & comp.std_rts(:, 2)<=endtime , :);

                comp.run_time_rts = comp.navdata_rts(:, 2) - starttime;
            end

            comp.refpos = cell2mat(comp.options.refpos);
            comp.refpos(1:2) = deg2rad(comp.refpos(1:2));
            lever = [0;0;0];
            if comp.options.isbacklever
                 lever = DRi(comp.refpos) * Euler2C(deg2rad(cell2mat(comp.options.initatt))) * cell2mat(comp.options.antlever);
            end
            comp.refpos = comp.refpos + lever;
            if ~comp.options.userts
                comp.RTSCheckBox.Value = false;
                comp.RTSCheckBox.Enable = false;
            end

            comp.set_button_state(true);
        end
        
        function set_button_state(comp, state)
            comp.Button_3.Enable = state;
            comp.Button_6.Enable = state;
            comp.Button_7.Enable = state;
            comp.Button_8.Enable = state;

            comp.Button_9.Enable = state;
            comp.Button_10.Enable = state;
            comp.Button_11.Enable = state;
            comp.Button_12.Enable = state;

            comp.NEDButton.Enable = state;
            comp.NEDButton_2.Enable = state;
            comp.Button_15.Enable = state;
            comp.Button_17.Enable = state;
            comp.Button_18.Enable = state;
            comp.Button_19.Enable = state;
            comp.Button_20.Enable = state;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            addpath("functions")
            comp.set_button_state(false);
            comp.Button_23.Enable = false;
            comp.Button_24.Enable = false;
        end

        % Button pushed function: Button_4
        function ButtonPushed(comp, event)
                % 打开文件夹选择对话框
                folder_path = uigetdir('', '选择结果文件夹');

                % 如果用户选择了文件夹（不是点击取消）
                if folder_path ~= 0
                    % 设置 EditField_result_path 的文本为所选文件夹路径
                    comp.EditField_result_path.Value = folder_path;
                end

                comp.load_result = true;

                if comp.load_yaml
                    load_results();
                end
        end

        % Button pushed function: Button_5
        function read_yaml_path(comp, event)
            % 打开文件选择对话框，只显示 .yaml 文件
            [file, path] = uigetfile({'*.yaml', 'YAML Files (*.yaml)'}, '选择 YAML 文件');

            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);

            % 设置 EditField_result_path 的文本为所选文件路径
            comp.EditField_yaml_path.Value = fullpath;
            comp.options =  yaml.loadFile(fullpath);

            comp.load_yaml = true;

            if comp.load_result
                comp.load_results;
            end
        end

        % Button pushed function: Button_6
        function Button_3Pushed(comp, event)
            str = "Height";
            if comp.options.userts && comp.RTSCheckBox.Value
                height = comp.navdata_rts(:, 5);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                height = comp.navdata(:, 5);
            end
            plot_height(time, height, str);
        end

        % Button pushed function: Button_3
        function Button_3Pushed2(comp, event)
            str = "Trajectory";
            if comp.options.userts && comp.RTSCheckBox.Value
                blh = comp.navdata_rts(:, 3:5);
                str = str + "_RTS";
            else
                blh = comp.navdata(:, 3:5);
            end
            plot_traj(blh, str, comp.refpos');
        end

        % Button pushed function: Button_7
        function Button_7Pushed(comp, event)
            str = "Velocity";
            if comp.options.userts && comp.RTSCheckBox.Value
                vel = comp.navdata_rts(:, 6:8);
                str = str + "_RTS";
                time = comp.run_time_rts;
            else
                vel = comp.navdata(:, 6:8);
                time = comp.run_time;
            end
            plot_vel(time, vel, str);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(comp, event)
            str = "Attitude";
            if comp.options.userts && comp.RTSCheckBox.Value
                att = comp.navdata_rts(:, 9:11);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                att = comp.navdata(:, 9:11);
            end
            plot_att(time, att, str);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(comp, event)
            str = "GyroBias";
            if comp.options.userts && comp.RTSCheckBox.Value
                gb = comp.imu_err_rts(:, 3:5);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                gb = comp.navdata(:, 3:5);
            end
            plot_gyro_bias(time, gb, str);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(comp, event)
            str = "AccelBias";
            if comp.options.userts && comp.RTSCheckBox.Value
                ab = comp.imu_err(:, 6:8);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                ab = comp.imu_err(:, 6:8);
            end
            plot_acc_bias(time, ab, str);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(comp, event)
            if comp.options.filtermode == 2
                % 弹出警告对话框
                d = warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
                % 等待用户关闭警告窗口
                uiwait(d);
            end
            str = "GyroScale";
            if comp.options.userts && comp.RTSCheckBox.Value
                gs = comp.imu_err_rts(:, 9:11);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                gs = comp.imu_err(:, 9:11);
            end
            plot_gyro_scale(time, gs, str);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(comp, event)
            if comp.options.filtermode == 2
                % 弹出警告对话框
                d = warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
                % 等待用户关闭警告窗口
                uiwait(d);
            end
            str = "AccelScale";
            if comp.options.userts && comp.RTSCheckBox.Value
                as = comp.imu_err_rts(:, 12:14);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                as = comp.imu_err(:, 12:14);
            end
            plot_acc_scale(time, as, str);
        end

        % Button pushed function: NEDButton
        function NEDButtonPushed(comp, event)
            str = "Pos STD";
            if comp.options.userts && comp.RTSCheckBox.Value
                posstd = comp.std_rts(:, 3:5);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                posstd = comp.std(:, 3:5);
            end
            plot_pos_std(time, posstd, str);
        end

        % Button pushed function: NEDButton_2
        function NEDButton_2Pushed(comp, event)
            str = "Vel STD";
            if comp.options.userts && comp.RTSCheckBox.Value
                velstd = comp.std_rts(:, 6:8);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                velstd = comp.std(:, 6:8);
            end
            plot_vel_std(time, velstd, str);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(comp, event)
            str = "Att STD";
            if comp.options.userts && comp.RTSCheckBox.Value
                attstd = comp.std_rts(:, 9:11);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                attstd = comp.std(:, 9:11);
            end
            plot_att_std(time, attstd, str);
        end

        % Button pushed function: Button_17
        function Button_17Pushed(comp, event)
            str = "Gyro Bias STD";
            if comp.options.userts && comp.RTSCheckBox.Value
                gbstd = comp.std_rts(:, 12:14);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                gbstd = comp.std(:, 12:14);
            end
            plot_gyrobias_std(time, gbstd, str);
        end

        % Button pushed function: Button_18
        function Button_18Pushed(comp, event)
            str = "Acc Bias STD";
            if comp.options.userts && comp.RTSCheckBox.Value
                abstd = comp.std_rts(:, 15:17);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                time = comp.run_time;
                abstd = comp.std(:, 15:17);
            end
            plot_accbias_std(time, abstd, str);
        end

        % Button pushed function: Button_19
        function Button_19Pushed(comp, event)
            if comp.options.filtermode == 2
                % 弹出警告对话框
                warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
            elseif comp.options.filtermode == 1
                str = "GyroScale STD";
                if comp.options.userts && comp.RTSCheckBox.Value
                    gsstd = comp.std_rts(:, 18:20);
                    time = comp.run_time_rts;
                    str = str + "_RTS";
                else
                    time = comp.run_time;
                    gsstd = comp.std(:, 18:20);
                end
                plot_gyroscale_std(time, gsstd, str);
            end
        end

        % Button pushed function: Button_20
        function Button_20Pushed(comp, event)
            if comp.options.filtermode == 2
                % 弹出警告对话框
                warndlg('该解算模式下比例因子不进行滤波解算！', '警告');
            elseif comp.options.filtermode ==1
                str = "AccelScale STD";
                if comp.options.userts && comp.RTSCheckBox.Value
                    gsstd = comp.std_rts(:, 21:23);
                    time = comp.run_time_rts;
                    str = str + "_RTS";
                else
                    time = comp.run_time;
                    gsstd = comp.std(:, 21:23);
                end
                plot_gyroscale_std(time, gsstd, str);
            end
        end

        % Button pushed function: Button_21
        function Button_21Pushed(comp, event)
            [file, path] = uigetfile({'*.txt', 'Laser Files (*.txt)'}, '选择 Txt 文件');

            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);

            % 设置文本为所选文件路径
            comp.EditField_laser_path.Value = fullpath;
            comp.laser_data = importdata(fullpath);

            if comp.load_result && comp.load_yaml
                comp.laser_data = comp.laser_data(comp.laser_data(:, 1)>comp.options.starttime, :);
                comp.Button_23.Enable =true;
            end
        end

        % Button pushed function: Button_23
        function Button_23Pushed(comp, event)
            % position
            str = "Laser - Ref";
            if comp.options.userts && comp.RTSCheckBox.Value
                blh = comp.navdata_rts(:, 3:5);
                time = comp.run_time_rts;
                str = str + "_RTS";
            else
                blh = comp.navdata(:, 3:5);
                time = comp.run_time;
            end
            blh(:, 1) = deg2rad(blh(:, 1));
            blh(:, 2) = deg2rad(blh(:, 2));
            ref_pos = comp.refpos';

            [rm, rn] = getRmRn(ref_pos(1));
            h = ref_pos(3);
            DR = diag([rm + h, (rn + h)*cos(ref_pos(1)), -1]);
            head = deg2rad(cell2mat(comp.options.initatt));
            head = head(3);
            C = [cos(head), -sin(head), 0; sin(head), cos(head), 0; 0, 0, 1];

            % blh to ned
            pos = zeros(size(blh));
            for i = 1:size(pos, 1)
                delta_blh = blh(i, :) - ref_pos;
                delta_pos = C * DR * delta_blh';
                pos(i, :) = delta_pos';
            end
            pos(:, 3) = -pos(:, 3);

            lasertime = comp.laser_data(:, 1) - comp.options.starttime;
            laser_disp =  comp.laser_data(:, 2:end) -  comp.laser_data(1, 2:end);

            if ~comp.CheckBox_4.Value && ~comp.CheckBox_5.Value && ~comp.CheckBox_6.Value
                % 弹出警告对话框
                warndlg('未选择激光对比轴向！', '警告');
                return
            elseif comp.CheckBox_4.Value && ~comp.CheckBox_5.Value && ~comp.CheckBox_6.Value
                laserdata = laser_disp(:, 1);
                data = pos(:, 1);
                ylabels = ["North[mm]"];
            elseif ~comp.CheckBox_4.Value && comp.CheckBox_5.Value && ~comp.CheckBox_6.Value
                laserdata = laser_disp(:, 2);
                data = pos(:, 2);
                ylabels = ["East[mm]"];
            elseif ~comp.CheckBox_4.Value && ~comp.CheckBox_5.Value && comp.CheckBox_6.Value
                laserdata = laser_disp(:, 3);
                data = pos(:, 3);
                ylabels = ["Height[mm]"];
            elseif comp.CheckBox_4.Value && comp.CheckBox_5.Value && ~comp.CheckBox_6.Value
                laserdata = laser_disp(:, 1:2);
                data = pos(:, 1:2);
                ylabels = ["North[mm]", "East[mm]"];
            elseif comp.CheckBox_4.Value && ~comp.CheckBox_5.Value && comp.CheckBox_6.Value
                laserdata = laser_disp(:, [1, 3]);
                data = pos(:, [1, 3]);
                ylabels = ["North[mm]", "Height[mm]"];
            elseif ~comp.CheckBox_4.Value && comp.CheckBox_5.Value && comp.CheckBox_6.Value
                laserdata = laser_disp(:, 2:3);
                data = pos(:, 2:3);
                ylabels = ["East[mm]", "Height[mm]"];
            else
                if width(data_disp) < 3
                    % 弹出警告对话框
                    warndlg('激光数据列数错误！', '警告');
                    return
                end
                    data = pos;
                    laserdata = laser_disp;
                    ylabels = ["North[mm]", "East[mm]", "Height[mm]"];
            end
            figure("Name",str)
            subs=[];
            N = width(data);
            for i = 1:N
                sub = subplot(N, 1, i);
                subs=[subs, sub];
                hold on
                plot(lasertime, laserdata(:, i), "LineWidth",2,"DisplayName","Laser");
                plot(time, data(:, i) * 1e3, "LineWidth",2,"DisplayName","KF-GINS");
                ylabel(ylabels(i))
                grid on
                legend
                hold off
                set(gca, 'FontSize', 20)
            end
            xlabel("Time[s]")
            linkaxes(subs, 'x');
        end

        % Button pushed function: Button_22
        function Button_22Pushed(comp, event)
            [file, path] = uigetfile({'*.pva', 'GINS Files (*.pva)'}, '选择 PVA 文件');

            % 如果用户没有点击取消
            if isequal(file, 0)
                return;  % 用户取消选择，直接退出函数
            end

            % 拼接完整路径
            fullpath = fullfile(path, file);

            % 设置文本为所选文件路径
            comp.EditField_gins_path.Value = fullpath;
            comp.readGinsPav(fullpath);

            if comp.load_result && comp.load_yaml
                comp.gins_data = comp.gins_data(comp.gins_data(:, 1)>comp.options.starttime, :);
                comp.Button_24.Enable =true;
            end
        end

        % Button pushed function: Button_24
        function Button_24Pushed(comp, event)
            str = "";
            if comp.options.userts
                time = comp.run_time_rts;
                nav = comp.navdata_rts;
                str = "_RTS";
            else
                time = comp.run_time;
                nav = comp.navdata;
            end
            pva_time = comp.gins_data(:, 1) - comp.options.starttime;

            if comp.CheckBox.Enable

                res_blh = nav(:, 3:5);
                res_blh(:, 1) = deg2rad(res_blh(:, 1));
                res_blh(:, 2) = deg2rad(res_blh(:, 2));
                pva_blh = comp.gins_data(:, 2:4);
                pva_blh(:, 1) = deg2rad(pva_blh(:, 1));
                pva_blh(:, 2) = deg2rad(pva_blh(:, 2));
                ref_pos = comp.refpos;

                [rm, rn] = getRmRn(ref_pos(1));
                h = ref_pos(3);
                DR = diag([rm + h, (rn + h)*cos(ref_pos(1)), -1]);
                % blh to ned
                res_pos = zeros(size(res_blh));
                pva_pos = zeros(size(pva_blh));
                for i = 1:size(res_pos, 1)
                    delta_blh = res_blh(i, :) - ref_pos';
                    delta_pos = DR * delta_blh';
                    res_pos(i, :) = delta_pos';
                end
                for i = 1:size(pva_pos, 1)
                    if comp.options.isbacklever
                        pva_blh(i, :) = pva_blh(i, :) +  (DRi(pva_blh(i, :)) * Euler2C(deg2rad(comp.gins_data(i, 5:7))) * cell2mat(comp.options.antlever))';
                    end
                    delta_blh = pva_blh(i, :) - ref_pos';
                    delta_pos = DR * delta_blh';
                    pva_pos(i, :) = delta_pos';
                end

                figure("Name", "Pos GINS - ref" + str);
                sub1 = subplot(3, 1, 1);
                hold on
                plot(time, res_pos(:, 1) * 1e3, "LineWidth", 2);
                plot(pva_time, pva_pos(:, 1) * 1e3, "LineWidth", 2);
                legend('KF-GINS', 'GINS');
                ylabel('North[mm]');
                grid("on");
                set(gca, 'FontSize', 20)

                sub2 = subplot(3,1,2);
                plot(time, res_pos(:, 2) * 1e3, "LineWidth", 2);
                hold on
                plot(pva_time, pva_pos(:, 2) * 1e3, "LineWidth", 2);
                legend('KF-GINS', 'GINS');
                ylabel('East[mm]');
                grid("on");
                set(gca, 'FontSize', 20)

                sub3 = subplot(3,1,3);
                plot(time, res_pos(:, 3) * 1e3, "LineWidth", 2);
                hold on
                plot(pva_time, pva_pos(:, 3) * 1e3, "LineWidth", 2);
                xlabel('Time[s]');
                legend('KF-GINS', 'GINS');
                ylabel('Height[mm]');
                grid("on");
                set(gca, 'FontSize', 20)

                linkaxes([sub1, sub2, sub3], 'x')
            end

            if comp.CheckBox_2.Value
                % velocity
                figure("Name", "Vel GINS - Ref" + str)
                sub1 = subplot(3, 1, 1);
                plot(time, nav(:, 6), "LineWidth", 2);
                hold on
                plot(pva_time, comp.gins_data(:, 8), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                ylabel('North Vel[m/s]');
                grid("on");
                set(gca, 'FontSize', 20)
                legend('KF-GINS', 'GINS');

                sub2 = subplot(3, 1, 2);
                plot(time, nav(:, 7), "LineWidth", 2);
                hold on
                plot(pva_time, comp.gins_data(:, 9), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                ylabel('East Vel[m/s]');
                grid("on");
                set(gca, 'FontSize', 20)

                sub3 = subplot(3, 1, 3);
                plot(time, nav(:, 8), "LineWidth", 2);
                hold on
                plot(pva_time, comp.gins_data(:, 10), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                xlabel('Time[s]');
                ylabel('Down Vel[m/s]');
                grid("on");
                set(gca, 'FontSize', 20)

                linkaxes([sub1, sub2, sub3], 'x');
            end

            if comp.CheckBox_3.Value
                % attitude
                figure("Name", "Att GINS - Ref" + str)
                sub1 = subplot(3, 1, 1);
                plot(time, nav(:, 9), "LineWidth", 2);
                hold on
                plot(pva_time, comp.gins_data(:, 5), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                ylabel('Roll [deg]');
                grid("on");
                set(gca, 'FontSize', 20)
                legend('KF-GINS', 'GINS');

                sub2 = subplot(3, 1, 2);
                plot(time, nav(:, 10), "LineWidth", 2);
                hold on
                plot(pva_time, comp.gins_data(:, 6), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                ylabel('Pitch [deg]');
                grid("on");
                set(gca, 'FontSize', 20)

                sub3 = subplot(3, 1, 3);
                plot(time, nav(:, 11), "LineWidth", 2);
                hold on
                plot(pva_time, comp.gins_data(:, 7), "LineWidth", 2)
                legend('KF-GINS', 'GINS');
                xlabel('Time[s]');
                ylabel('Yaw [deg]');
                grid("on");
                set(gca, 'FontSize', 20)

                linkaxes([sub1, sub2, sub3], 'x');
            end
        end

        % Value changed function: EditField_result_path
        function EditField_result_pathValueChanged(comp, event)
            comp.load_result = true;

            if comp.load_yaml
                load_results();
            end
        end

        % Value changed function: EditField_yaml_path
        function EditField_yaml_pathValueChanged(comp, event)
            value = comp.EditField_yaml_path.Value;
            comp.options =  yaml.loadFile(value);

            comp.load_yaml = true;

            if comp.load_result
                comp.load_results;
            end
        end
    end

    methods (Access = protected)
        
        % 属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
            
        end

        % 创建底层组件
        function setup(comp)

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            comp.Position = [1 1 1345 773];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'1x'};
            comp.GridLayout.RowHeight = {150, '4x', 270};

            % Create Panel
            comp.Panel = uipanel(comp.GridLayout);
            comp.Panel.Title = '   绘图选项';
            comp.Panel.Layout.Row = 2;
            comp.Panel.Layout.Column = 1;
            comp.Panel.FontName = '华文中宋';
            comp.Panel.FontSize = 24;

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.Panel);
            comp.GridLayout3.ColumnWidth = {'1x', '0.1x'};
            comp.GridLayout3.RowHeight = {'1x', '1x', '2x'};
            comp.GridLayout3.ColumnSpacing = 5;
            comp.GridLayout3.RowSpacing = 5;
            comp.GridLayout3.Padding = [0 0 0 0];

            % Create GridLayout4
            comp.GridLayout4 = uigridlayout(comp.GridLayout3);
            comp.GridLayout4.ColumnWidth = {100, '60x', '60x', '60x', '60x'};
            comp.GridLayout4.RowHeight = {'fit'};
            comp.GridLayout4.ColumnSpacing = 20;
            comp.GridLayout4.Padding = [20 10 10 25];
            comp.GridLayout4.Layout.Row = 1;
            comp.GridLayout4.Layout.Column = 1;

            % Create Button_3
            comp.Button_3 = uibutton(comp.GridLayout4, 'push');
            comp.Button_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_3Pushed2, true);
            comp.Button_3.IconAlignment = 'center';
            comp.Button_3.FontName = '华文中宋';
            comp.Button_3.FontSize = 24;
            comp.Button_3.Layout.Row = 1;
            comp.Button_3.Layout.Column = 2;
            comp.Button_3.Text = '轨迹';

            % Create Label_3
            comp.Label_3 = uilabel(comp.GridLayout4);
            comp.Label_3.HorizontalAlignment = 'center';
            comp.Label_3.FontName = '华文中宋';
            comp.Label_3.FontSize = 24;
            comp.Label_3.Layout.Row = 1;
            comp.Label_3.Layout.Column = 1;
            comp.Label_3.Text = '导航状态';

            % Create Button_6
            comp.Button_6 = uibutton(comp.GridLayout4, 'push');
            comp.Button_6.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_3Pushed, true);
            comp.Button_6.IconAlignment = 'center';
            comp.Button_6.FontName = '华文中宋';
            comp.Button_6.FontSize = 24;
            comp.Button_6.Layout.Row = 1;
            comp.Button_6.Layout.Column = 3;
            comp.Button_6.Text = '高程';

            % Create Button_7
            comp.Button_7 = uibutton(comp.GridLayout4, 'push');
            comp.Button_7.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_7Pushed, true);
            comp.Button_7.IconAlignment = 'center';
            comp.Button_7.FontName = '华文中宋';
            comp.Button_7.FontSize = 24;
            comp.Button_7.Layout.Row = 1;
            comp.Button_7.Layout.Column = 4;
            comp.Button_7.Text = '速度';

            % Create Button_8
            comp.Button_8 = uibutton(comp.GridLayout4, 'push');
            comp.Button_8.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_8Pushed, true);
            comp.Button_8.IconAlignment = 'center';
            comp.Button_8.FontName = '华文中宋';
            comp.Button_8.FontSize = 24;
            comp.Button_8.Layout.Row = 1;
            comp.Button_8.Layout.Column = 5;
            comp.Button_8.Text = '姿态';

            % Create GridLayout4_2
            comp.GridLayout4_2 = uigridlayout(comp.GridLayout3);
            comp.GridLayout4_2.ColumnWidth = {100, '60x', '60x', '60x', '60x'};
            comp.GridLayout4_2.RowHeight = {'fit'};
            comp.GridLayout4_2.ColumnSpacing = 20;
            comp.GridLayout4_2.Padding = [20 10 10 25];
            comp.GridLayout4_2.Layout.Row = 2;
            comp.GridLayout4_2.Layout.Column = 1;

            % Create Label_4
            comp.Label_4 = uilabel(comp.GridLayout4_2);
            comp.Label_4.HorizontalAlignment = 'center';
            comp.Label_4.FontName = '华文中宋';
            comp.Label_4.FontSize = 24;
            comp.Label_4.Layout.Row = 1;
            comp.Label_4.Layout.Column = 1;
            comp.Label_4.Text = '惯导误差';

            % Create Button_9
            comp.Button_9 = uibutton(comp.GridLayout4_2, 'push');
            comp.Button_9.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_9Pushed, true);
            comp.Button_9.IconAlignment = 'center';
            comp.Button_9.FontName = '华文中宋';
            comp.Button_9.FontSize = 24;
            comp.Button_9.Layout.Row = 1;
            comp.Button_9.Layout.Column = 2;
            comp.Button_9.Text = '陀螺零偏';

            % Create Button_10
            comp.Button_10 = uibutton(comp.GridLayout4_2, 'push');
            comp.Button_10.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_10Pushed, true);
            comp.Button_10.IconAlignment = 'center';
            comp.Button_10.FontName = '华文中宋';
            comp.Button_10.FontSize = 24;
            comp.Button_10.Layout.Row = 1;
            comp.Button_10.Layout.Column = 3;
            comp.Button_10.Text = '加速度计零偏';

            % Create Button_11
            comp.Button_11 = uibutton(comp.GridLayout4_2, 'push');
            comp.Button_11.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_11Pushed, true);
            comp.Button_11.IconAlignment = 'center';
            comp.Button_11.FontName = '华文中宋';
            comp.Button_11.FontSize = 24;
            comp.Button_11.Layout.Row = 1;
            comp.Button_11.Layout.Column = 4;
            comp.Button_11.Text = '陀螺比例因子';

            % Create Button_12
            comp.Button_12 = uibutton(comp.GridLayout4_2, 'push');
            comp.Button_12.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_12Pushed, true);
            comp.Button_12.IconAlignment = 'center';
            comp.Button_12.FontName = '华文中宋';
            comp.Button_12.FontSize = 24;
            comp.Button_12.Layout.Row = 1;
            comp.Button_12.Layout.Column = 5;
            comp.Button_12.Text = '加速度计比例因子';

            % Create GridLayout4_3
            comp.GridLayout4_3 = uigridlayout(comp.GridLayout3);
            comp.GridLayout4_3.ColumnWidth = {100, '80x', '80x', '80x', '80x'};
            comp.GridLayout4_3.RowHeight = {'fit', 'fit'};
            comp.GridLayout4_3.ColumnSpacing = 20;
            comp.GridLayout4_3.RowSpacing = 20;
            comp.GridLayout4_3.Padding = [20 10 10 25];
            comp.GridLayout4_3.Layout.Row = 3;
            comp.GridLayout4_3.Layout.Column = 1;

            % Create Label_5
            comp.Label_5 = uilabel(comp.GridLayout4_3);
            comp.Label_5.HorizontalAlignment = 'center';
            comp.Label_5.FontName = '华文中宋';
            comp.Label_5.FontSize = 24;
            comp.Label_5.Layout.Row = 1;
            comp.Label_5.Layout.Column = 1;
            comp.Label_5.Text = '标准差';

            % Create NEDButton
            comp.NEDButton = uibutton(comp.GridLayout4_3, 'push');
            comp.NEDButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NEDButtonPushed, true);
            comp.NEDButton.IconAlignment = 'center';
            comp.NEDButton.FontName = '华文中宋';
            comp.NEDButton.FontSize = 24;
            comp.NEDButton.Layout.Row = 1;
            comp.NEDButton.Layout.Column = 2;
            comp.NEDButton.Text = 'NED位置';

            % Create NEDButton_2
            comp.NEDButton_2 = uibutton(comp.GridLayout4_3, 'push');
            comp.NEDButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NEDButton_2Pushed, true);
            comp.NEDButton_2.IconAlignment = 'center';
            comp.NEDButton_2.FontName = '华文中宋';
            comp.NEDButton_2.FontSize = 24;
            comp.NEDButton_2.Layout.Row = 1;
            comp.NEDButton_2.Layout.Column = 3;
            comp.NEDButton_2.Text = 'NED速度';

            % Create Button_15
            comp.Button_15 = uibutton(comp.GridLayout4_3, 'push');
            comp.Button_15.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_15Pushed, true);
            comp.Button_15.IconAlignment = 'center';
            comp.Button_15.FontName = '华文中宋';
            comp.Button_15.FontSize = 24;
            comp.Button_15.Layout.Row = 1;
            comp.Button_15.Layout.Column = 4;
            comp.Button_15.Text = '姿态';

            % Create Button_17
            comp.Button_17 = uibutton(comp.GridLayout4_3, 'push');
            comp.Button_17.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_17Pushed, true);
            comp.Button_17.IconAlignment = 'center';
            comp.Button_17.FontName = '华文中宋';
            comp.Button_17.FontSize = 24;
            comp.Button_17.Layout.Row = 2;
            comp.Button_17.Layout.Column = 2;
            comp.Button_17.Text = '陀螺零偏';

            % Create Button_18
            comp.Button_18 = uibutton(comp.GridLayout4_3, 'push');
            comp.Button_18.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_18Pushed, true);
            comp.Button_18.IconAlignment = 'center';
            comp.Button_18.FontName = '华文中宋';
            comp.Button_18.FontSize = 24;
            comp.Button_18.Layout.Row = 2;
            comp.Button_18.Layout.Column = 3;
            comp.Button_18.Text = '加速度计零偏';

            % Create Button_19
            comp.Button_19 = uibutton(comp.GridLayout4_3, 'push');
            comp.Button_19.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_19Pushed, true);
            comp.Button_19.IconAlignment = 'center';
            comp.Button_19.FontName = '华文中宋';
            comp.Button_19.FontSize = 24;
            comp.Button_19.Layout.Row = 2;
            comp.Button_19.Layout.Column = 4;
            comp.Button_19.Text = '陀螺比例因子';

            % Create Button_20
            comp.Button_20 = uibutton(comp.GridLayout4_3, 'push');
            comp.Button_20.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_20Pushed, true);
            comp.Button_20.IconAlignment = 'center';
            comp.Button_20.FontName = '华文中宋';
            comp.Button_20.FontSize = 24;
            comp.Button_20.Layout.Row = 2;
            comp.Button_20.Layout.Column = 5;
            comp.Button_20.Text = '加速度计比例因子';

            % Create RTSCheckBox
            comp.RTSCheckBox = uicheckbox(comp.GridLayout3);
            comp.RTSCheckBox.Text = 'RTS';
            comp.RTSCheckBox.FontName = 'Arial';
            comp.RTSCheckBox.FontSize = 24;
            comp.RTSCheckBox.Layout.Row = 1;
            comp.RTSCheckBox.Layout.Column = 2;

            % Create Panel_2
            comp.Panel_2 = uipanel(comp.GridLayout);
            comp.Panel_2.Title = '   文件路径';
            comp.Panel_2.Layout.Row = 1;
            comp.Panel_2.Layout.Column = 1;
            comp.Panel_2.FontName = '华文中宋';
            comp.Panel_2.FontSize = 24;

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.Panel_2);
            comp.GridLayout2.ColumnWidth = {200, '1x', '0.15x'};

            % Create Label
            comp.Label = uilabel(comp.GridLayout2);
            comp.Label.HorizontalAlignment = 'center';
            comp.Label.FontName = '华文中宋';
            comp.Label.FontSize = 24;
            comp.Label.Layout.Row = 1;
            comp.Label.Layout.Column = 1;
            comp.Label.Text = '结果文件路径';

            % Create EditField_result_path
            comp.EditField_result_path = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_result_path.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_result_pathValueChanged, true);
            comp.EditField_result_path.FontName = 'Arial';
            comp.EditField_result_path.FontSize = 24;
            comp.EditField_result_path.Layout.Row = 1;
            comp.EditField_result_path.Layout.Column = 2;

            % Create Label_2
            comp.Label_2 = uilabel(comp.GridLayout2);
            comp.Label_2.HorizontalAlignment = 'center';
            comp.Label_2.FontName = '华文中宋';
            comp.Label_2.FontSize = 24;
            comp.Label_2.Layout.Row = 2;
            comp.Label_2.Layout.Column = 1;
            comp.Label_2.Text = '配置文件路径';

            % Create EditField_yaml_path
            comp.EditField_yaml_path = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_yaml_path.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_yaml_pathValueChanged, true);
            comp.EditField_yaml_path.FontName = 'Arial';
            comp.EditField_yaml_path.FontSize = 24;
            comp.EditField_yaml_path.Layout.Row = 2;
            comp.EditField_yaml_path.Layout.Column = 2;

            % Create Button_4
            comp.Button_4 = uibutton(comp.GridLayout2, 'push');
            comp.Button_4.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button_4.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            comp.Button_4.IconAlignment = 'center';
            comp.Button_4.VerticalAlignment = 'top';
            comp.Button_4.Layout.Row = 1;
            comp.Button_4.Layout.Column = 3;
            comp.Button_4.Text = '';

            % Create Button_5
            comp.Button_5 = uibutton(comp.GridLayout2, 'push');
            comp.Button_5.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @read_yaml_path, true);
            comp.Button_5.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            comp.Button_5.IconAlignment = 'center';
            comp.Button_5.Layout.Row = 2;
            comp.Button_5.Layout.Column = 3;
            comp.Button_5.Text = '';

            % Create Panel_3
            comp.Panel_3 = uipanel(comp.GridLayout);
            comp.Panel_3.Title = '   参考值';
            comp.Panel_3.Layout.Row = 3;
            comp.Panel_3.Layout.Column = 1;
            comp.Panel_3.FontName = '华文中宋';
            comp.Panel_3.FontSize = 24;

            % Create GridLayout2_2
            comp.GridLayout2_2 = uigridlayout(comp.Panel_3);
            comp.GridLayout2_2.ColumnWidth = {200, '1x', '0.15x'};
            comp.GridLayout2_2.RowHeight = {50, 40, 50, 40};

            % Create Label_6
            comp.Label_6 = uilabel(comp.GridLayout2_2);
            comp.Label_6.HorizontalAlignment = 'center';
            comp.Label_6.FontName = '华文中宋';
            comp.Label_6.FontSize = 24;
            comp.Label_6.Layout.Row = 1;
            comp.Label_6.Layout.Column = 1;
            comp.Label_6.Text = '激光参考值路径';

            % Create EditField_laser_path
            comp.EditField_laser_path = uieditfield(comp.GridLayout2_2, 'text');
            comp.EditField_laser_path.FontName = 'Arial';
            comp.EditField_laser_path.FontSize = 24;
            comp.EditField_laser_path.Layout.Row = 1;
            comp.EditField_laser_path.Layout.Column = 2;

            % Create Label_7
            comp.Label_7 = uilabel(comp.GridLayout2_2);
            comp.Label_7.HorizontalAlignment = 'center';
            comp.Label_7.FontName = '华文中宋';
            comp.Label_7.FontSize = 24;
            comp.Label_7.Layout.Row = 3;
            comp.Label_7.Layout.Column = 1;
            comp.Label_7.Text = 'GINS参考值路径';

            % Create EditField_gins_path
            comp.EditField_gins_path = uieditfield(comp.GridLayout2_2, 'text');
            comp.EditField_gins_path.FontName = 'Arial';
            comp.EditField_gins_path.FontSize = 24;
            comp.EditField_gins_path.Layout.Row = 3;
            comp.EditField_gins_path.Layout.Column = 2;

            % Create Button_21
            comp.Button_21 = uibutton(comp.GridLayout2_2, 'push');
            comp.Button_21.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_21Pushed, true);
            comp.Button_21.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            comp.Button_21.IconAlignment = 'center';
            comp.Button_21.VerticalAlignment = 'top';
            comp.Button_21.Layout.Row = 1;
            comp.Button_21.Layout.Column = 3;
            comp.Button_21.Text = '';

            % Create Button_22
            comp.Button_22 = uibutton(comp.GridLayout2_2, 'push');
            comp.Button_22.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_22Pushed, true);
            comp.Button_22.Icon = fullfile(pathToMLAPP, 'icons', '文件夹.png');
            comp.Button_22.IconAlignment = 'center';
            comp.Button_22.Layout.Row = 3;
            comp.Button_22.Layout.Column = 3;
            comp.Button_22.Text = '';

            % Create GridLayout5
            comp.GridLayout5 = uigridlayout(comp.GridLayout2_2);
            comp.GridLayout5.ColumnWidth = {120, 120, 120};
            comp.GridLayout5.RowHeight = {30};
            comp.GridLayout5.ColumnSpacing = 50;
            comp.GridLayout5.Padding = [10 10 10 0];
            comp.GridLayout5.Layout.Row = 2;
            comp.GridLayout5.Layout.Column = 2;

            % Create CheckBox_4
            comp.CheckBox_4 = uicheckbox(comp.GridLayout5);
            comp.CheckBox_4.Text = '前向';
            comp.CheckBox_4.FontName = '华文中宋';
            comp.CheckBox_4.FontSize = 24;
            comp.CheckBox_4.Layout.Row = 1;
            comp.CheckBox_4.Layout.Column = 1;

            % Create CheckBox_5
            comp.CheckBox_5 = uicheckbox(comp.GridLayout5);
            comp.CheckBox_5.Text = '侧向';
            comp.CheckBox_5.FontName = '华文中宋';
            comp.CheckBox_5.FontSize = 24;
            comp.CheckBox_5.Layout.Row = 1;
            comp.CheckBox_5.Layout.Column = 2;

            % Create CheckBox_6
            comp.CheckBox_6 = uicheckbox(comp.GridLayout5);
            comp.CheckBox_6.Text = '垂向';
            comp.CheckBox_6.FontName = '华文中宋';
            comp.CheckBox_6.FontSize = 24;
            comp.CheckBox_6.Layout.Row = 1;
            comp.CheckBox_6.Layout.Column = 3;

            % Create Button_23
            comp.Button_23 = uibutton(comp.GridLayout2_2, 'push');
            comp.Button_23.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_23Pushed, true);
            comp.Button_23.FontName = '华文中宋';
            comp.Button_23.FontSize = 24;
            comp.Button_23.Layout.Row = 2;
            comp.Button_23.Layout.Column = 3;
            comp.Button_23.Text = '绘制';

            % Create Button_24
            comp.Button_24 = uibutton(comp.GridLayout2_2, 'push');
            comp.Button_24.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_24Pushed, true);
            comp.Button_24.FontName = '华文中宋';
            comp.Button_24.FontSize = 24;
            comp.Button_24.Layout.Row = 4;
            comp.Button_24.Layout.Column = 3;
            comp.Button_24.Text = '绘制';

            % Create GridLayout5_2
            comp.GridLayout5_2 = uigridlayout(comp.GridLayout2_2);
            comp.GridLayout5_2.ColumnWidth = {120, 120, 120};
            comp.GridLayout5_2.RowHeight = {30};
            comp.GridLayout5_2.ColumnSpacing = 50;
            comp.GridLayout5_2.Padding = [10 10 10 0];
            comp.GridLayout5_2.Layout.Row = 4;
            comp.GridLayout5_2.Layout.Column = 2;

            % Create CheckBox
            comp.CheckBox = uicheckbox(comp.GridLayout5_2);
            comp.CheckBox.Text = '位置';
            comp.CheckBox.FontName = '华文中宋';
            comp.CheckBox.FontSize = 24;
            comp.CheckBox.Layout.Row = 1;
            comp.CheckBox.Layout.Column = 1;

            % Create CheckBox_2
            comp.CheckBox_2 = uicheckbox(comp.GridLayout5_2);
            comp.CheckBox_2.Text = '速度';
            comp.CheckBox_2.FontName = '华文中宋';
            comp.CheckBox_2.FontSize = 24;
            comp.CheckBox_2.Layout.Row = 1;
            comp.CheckBox_2.Layout.Column = 2;

            % Create CheckBox_3
            comp.CheckBox_3 = uicheckbox(comp.GridLayout5_2);
            comp.CheckBox_3.Text = '姿态';
            comp.CheckBox_3.FontName = '华文中宋';
            comp.CheckBox_3.FontSize = 24;
            comp.CheckBox_3.Layout.Row = 1;
            comp.CheckBox_3.Layout.Column = 3;
            
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end